classdef fourier_series_filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FourierSeriesfiltersUIFigure  matlab.ui.Figure
        optionsPanel                  matlab.ui.container.Panel
        filterButton                  matlab.ui.control.Button
        Fs_endEditField               matlab.ui.control.NumericEditField
        Fs_endEditFieldLabel          matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        fileEditField                 matlab.ui.control.EditField
        fileEditFieldLabel            matlab.ui.control.Label
        Fs_startEditField             matlab.ui.control.NumericEditField
        Fs_startEditFieldLabel        matlab.ui.control.Label
        FiltersDropDown               matlab.ui.control.DropDown
        FiltersDropDownLabel          matlab.ui.control.Label
        AxesPanel                     matlab.ui.container.Panel
        FilterdetailsLabel            matlab.ui.control.Label
        stopButton_2                  matlab.ui.control.Button
        playButton_2                  matlab.ui.control.Button
        stopButton                    matlab.ui.control.Button
        playButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Signal          % Signal simples return from readaudio for the original signal
        FilterdSignal   % Signal simples return from filter function for the filtered signal
        SignalFs        % Signal frequancy return from readaudio for the original signal
        SignalTime      % Signal Time based on Signal frequancy
    end
    
    methods (Access = public)
        
        function DisplayInputs(app,fs_start_state,fs_end_state)
            % state of fs_start input field visiblity
            app.Fs_startEditField.Visible = fs_start_state;
            app.Fs_startEditFieldLabel.Visible = fs_start_state;
            % state of fs_end input field visiblity
            app.Fs_endEditField.Visible = fs_end_state;
            app.Fs_endEditFieldLabel.Visible = fs_end_state;
            % state of filterButton visiblity
            app.filterButton.Visible = fs_start_state;
        end
        
        function filterAxes(app,y,msg)
            % save filtered signal in FilterdSignal properity
            app.FilterdSignal = y;
            % draw filtered signal on UIAxes (the bottom axes)
            plot(app.UIAxes,app.SignalTime,y);
            % display the filter details on the label
            app.FilterdetailsLabel.Text = msg;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % open Browse window to select file with extintion .wav or .mp3
            [filename,filepath] = uigetfile({'*.wav;*.mp3'});
            % mix filepath with filename to generate full file name
            fullfilename = [filepath filename];
            if filepath ~= 0
                % change file edit field valut to full file name
                app.fileEditField.Value = fullfilename;
                
                % read full file name to generate audio signal and audio
                % frequancy
                [y,fs] = audioread(fullfilename);
    
                % get information about full file name
                info = audioinfo(fullfilename);
    
                % save audio signal in Signal properity
                app.Signal = y;
                % save audio frequancy in SignalFs properity
                app.SignalFs = fs;
    
                % generate time based on signal frequancy and audio file
                % duration
                t = 0:seconds(1/fs):seconds(info.Duration);
                t = t(1:end-1);
    
                % save signal time in SignalTime properity
                app.SignalTime = t;
               
                % draw audio signal in UIAxes_2 (above axes)
                plot(app.UIAxes_2,t,y);
    
                % display play audio and stop audio buttons
                app.playButton.Visible = "on";
                app.stopButton.Visible = "on";
            end
            
        end

        % Value changed function: FiltersDropDown
        function FiltersDropDownValueChanged(app, event)
            % get filter kind from dropdown
            value = app.FiltersDropDown.Value;
            % display inputs fields based on filter kind
            switch value
                case {"Low pass filter","High pass filter"}
                    DisplayInputs(app,"on","off");
                case "Band pass filter"
                    DisplayInputs(app,"on","on");
                otherwise
                    DisplayInputs(app,"off","off");
            end
        end

        % Button pushed function: filterButton
        function filterButtonPushed(app, event)
            %  get filter kind from dropdown
            filter = app.FiltersDropDown.Value;
            % get fs_start from input field
            fs_start = app.Fs_startEditField.Value;
            % get fs_end from input field
            fs_end = app.Fs_endEditField.Value;
            % get audio signal from Siganl prop
            x = app.Signal;
            % get audio frequancy from SignalFs prop
            fs = app.SignalFs;
            % operates filter ,draw it ,and display filter details 
            switch filter
                case "Low pass filter"
                    y = lowpass(x,fs_start,fs);
                    filterAxes(app,y,"Lowpass filter fs: " + fs_start)
                case "High pass filter"
                    y = highpass(x,fs_start,fs);
                    filterAxes(app,y,"Highpass filter fs: " + fs_start)
                case "Band pass filter"                    
                    y = bandpass(x,[fs_start fs_end],fs);
                    filterAxes(app,y,"Bandpass filter fs: " + fs_start + " - " + fs_end)
            end
            % display play audio and stop audio buttons for filtered signal
            app.playButton_2.Visible = "on";
            app.stopButton_2.Visible = "on";
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            % play orignal audio by audio signal and audio frequancy
            sound(app.Signal,app.SignalFs);
        end

        % Button pushed function: stopButton, stopButton_2
        function stopButtonPushed(app, event)
            % stop playing audio
            clear sound;
        end

        % Button pushed function: playButton_2
        function playButton_2Pushed(app, event)
            % play filtered audio by audio filtered signal and audio frequancy
            sound(app.FilterdSignal,app.SignalFs);
        end

        % Close request function: FourierSeriesfiltersUIFigure
        function FourierSeriesfiltersUIFigureCloseRequest(app, event)
            delete(app)
            % stop playing audio
            clear sound;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FourierSeriesfiltersUIFigure = uifigure('Visible', 'off');
            app.FourierSeriesfiltersUIFigure.Name = 'Fourier Series filters ';
            app.FourierSeriesfiltersUIFigure.Icon = fullfile(pathToMLAPP, '2509317.png');
            app.FourierSeriesfiltersUIFigure.CloseRequestFcn = createCallbackFcn(app, @FourierSeriesfiltersUIFigureCloseRequest, true);
            app.AxesPanel = uipanel(app.FourierSeriesfiltersUIFigure);
            app.AxesPanel.Title = 'Axes';
            app.UIAxes_2 = uiaxes(app.AxesPanel);
            title(app.UIAxes_2, 'Original')
            xlabel(app.UIAxes_2, 'time')
            ylabel(app.UIAxes_2, 'amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.AxesPanel);
            title(app.UIAxes, 'filtered')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'amplitude')
            zlabel(app.UIAxes, 'Z')
            app.playButton = uibutton(app.AxesPanel, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Visible = 'off';
            app.playButton.Text = 'play';
            app.stopButton = uibutton(app.AxesPanel, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Visible = 'off';
            app.stopButton.Text = 'stop';
            app.playButton_2 = uibutton(app.AxesPanel, 'push');
            app.playButton_2.ButtonPushedFcn = createCallbackFcn(app, @playButton_2Pushed, true);
            app.playButton_2.Visible = 'off';
            app.playButton_2.Text = 'play';
            app.stopButton_2 = uibutton(app.AxesPanel, 'push');
            app.stopButton_2.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton_2.Visible = 'off';
            app.stopButton_2.Text = 'stop';
            app.FilterdetailsLabel = uilabel(app.AxesPanel);
            app.FilterdetailsLabel.Text = 'Filter details';
            app.optionsPanel = uipanel(app.FourierSeriesfiltersUIFigure);
            app.optionsPanel.Title = 'options';
            app.FiltersDropDownLabel = uilabel(app.optionsPanel);
            app.FiltersDropDownLabel.Text = 'Filters';
            app.FiltersDropDown = uidropdown(app.optionsPanel);
            app.FiltersDropDown.Items = {'Choose filter', 'Low pass filter', 'Band pass filter', 'High pass filter'};
            app.FiltersDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltersDropDownValueChanged, true);
            app.FiltersDropDown.Value = 'Choose filter';
            app.Fs_startEditFieldLabel = uilabel(app.optionsPanel);
            app.Fs_startEditFieldLabel.Visible = 'off';
            app.Fs_startEditFieldLabel.Text = 'Fs_start';
            app.Fs_startEditField = uieditfield(app.optionsPanel, 'numeric');
            app.Fs_startEditField.Visible = 'off';
            app.fileEditFieldLabel = uilabel(app.optionsPanel);
            app.fileEditFieldLabel.Text = 'file';
            app.fileEditField = uieditfield(app.optionsPanel, 'text');
            app.BrowseButton = uibutton(app.optionsPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.Fs_endEditFieldLabel = uilabel(app.optionsPanel);
            app.Fs_endEditFieldLabel.Visible = 'off';
            app.Fs_endEditFieldLabel.Text = 'Fs_end';
            app.Fs_endEditField = uieditfield(app.optionsPanel, 'numeric');
            app.Fs_endEditField.Visible = 'off';
            app.filterButton = uibutton(app.optionsPanel, 'push');
            app.filterButton.ButtonPushedFcn = createCallbackFcn(app, @filterButtonPushed, true);
            app.filterButton.Visible = 'off';
            app.filterButton.Text = 'filter';
            % Show the figure after all components are created
            app.FourierSeriesfiltersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fourier_series_filters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FourierSeriesfiltersUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FourierSeriesfiltersUIFigure)
        end
    end
end